classdef EBM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USRA_EBM                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        RCPsTab                   matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        ClimatePathwayPanel       matlab.ui.container.Panel
        RCP24CheckBox             matlab.ui.control.CheckBox
        RCP60CheckBox             matlab.ui.control.CheckBox
        RCP45CheckBox             matlab.ui.control.CheckBox
        RCP85CheckBox             matlab.ui.control.CheckBox
        clearplotButton           matlab.ui.control.Button
        GenerateButton            matlab.ui.control.Button
        Switch                    matlab.ui.control.Switch
        Label                     matlab.ui.control.Label
        StartYearSpinnerLabel     matlab.ui.control.Label
        StartYearSpinner          matlab.ui.control.Spinner
        EndYearSpinnerLabel       matlab.ui.control.Label
        EndYearSpinner            matlab.ui.control.Spinner
        ToolsTab                  matlab.ui.container.Tab
        ForcingsPanel             matlab.ui.container.Panel
        FAWm2EditFieldLabel       matlab.ui.control.Label
        FAWm2EditField            matlab.ui.control.NumericEditField
        FOWm2EditFieldLabel       matlab.ui.control.Label
        FOWm2EditField            matlab.ui.control.NumericEditField
        QWm2Label                 matlab.ui.control.Label
        QWm2EditField             matlab.ui.control.NumericEditField
        deltaLabel                matlab.ui.control.Label
        deltaEditField            matlab.ui.control.NumericEditField
        CalculatorPanel           matlab.ui.container.Panel
        CalculateButton           matlab.ui.control.Button
        BiasButtonGroup           matlab.ui.container.ButtonGroup
        ColdButton                matlab.ui.control.RadioButton
        TemperateButton           matlab.ui.control.RadioButton
        WarmButton                matlab.ui.control.RadioButton
        GHGsPanel                 matlab.ui.container.Panel
        muppmEditFieldLabel       matlab.ui.control.Label
        muppmEditField            matlab.ui.control.NumericEditField
        nuppbEditFieldLabel       matlab.ui.control.Label
        nuppbEditField            matlab.ui.control.NumericEditField
        tau_sEditFieldLabel       matlab.ui.control.Label
        tau_sEditField            matlab.ui.control.NumericEditField
        HRpermafrostrelatedPanel  matlab.ui.container.Panel
        Q10EditFieldLabel         matlab.ui.control.Label
        Q10EditField              matlab.ui.control.NumericEditField
        LabelonplotCheckBox       matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %app.GenerateButton.BackgroundColor = [1,0,0];
            clear r; 
            r = rcpsim(app.FOWm2EditField.Value, app.FAWm2EditField.Value, app.QWm2EditField.Value, app.deltaEditField.Value);
            %set params 
            r.ebm.Q_10 = app.Q10EditField.Value;
            
            pathways = [];
            if app.RCP24CheckBox.Value 
                pathways = [pathways "2.4"];
            end
            if app.RCP45CheckBox.Value 
                pathways = [pathways "4.5"];
            end
            if app.RCP60CheckBox.Value 
                pathways = [pathways "6.0"];
            end
            if app.RCP85CheckBox.Value 
                pathways = [pathways "8.5"];
            end
            
            %years associated w/ rcp's
            times = linspace(app.StartYearSpinner.Value, app.EndYearSpinner.Value, app.EndYearSpinner.Value - app.StartYearSpinner.Value+1);
            xlim(app.UIAxes, [app.StartYearSpinner.Value app.EndYearSpinner.Value])
            
            %sequential plotting
            if app.Switch.Value == "No Permafrost"
                for i = 1:length(pathways)
                    clear t 
                    t = RCPNoPermafrost(r, pathways(i), [app.StartYearSpinner.Value app.EndYearSpinner.Value]);
                    plot(app.UIAxes, times, t, 'DisplayName', "RCP " + pathways(i));
                    hold(app.UIAxes, "on") 
                end 
            else 
                %for display purposes
                if app.LabelonplotCheckBox.Value
                    suffix = "(P, " + "Q10 = " + num2str(r.ebm.Q_10) + ")";
                else 
                    suffix = "(P)";
                end
                    
                for i = 1:length(pathways)
                    clear t 
                    t = RCPrk3ab3(r, pathways(i), [app.StartYearSpinner.Value app.EndYearSpinner.Value]);
                    plot(app.UIAxes, times, t, 'DisplayName', "RCP " + pathways(i)+ " " + suffix);
                    hold(app.UIAxes, "on")
                end 
            end 
            lgd = legend(app.UIAxes,'Location','northwest');
            lgd.FontSize = 8;
            %title(app.UIAxes, "RCP: " + strjoin(pathways,', ')) 
        end

        % Button pushed function: clearplotButton
        function clearplotButtonPushed(app, event)
            cla(app.UIAxes);
            legend(app.UIAxes, "off");
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            e = ebm(app.FOWm2EditField.Value, app.FAWm2EditField.Value, app.QWm2EditField.Value, app.deltaEditField.Value);
            e.mu = app.muppmEditField.Value;
            e.nu = app.nuppbEditField.Value;
            if app.ColdButton.Value
                eqtemp(e,0.9);
                app.tau_sEditField.Value = e.tau_s;
            elseif app.TemperateButton.Value
                eqtemp(e,1);
                app.tau_sEditField.Value = e.tau_s;
            elseif app.WarmButton.Value
                eqtemp(e,1.03);
                app.tau_sEditField.Value = e.tau_s;   
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.USRA_EBM = uifigure('Visible', 'off');
            app.USRA_EBM.Name = 'EBM';
            app.TabGroup = uitabgroup(app.USRA_EBM);
            app.RCPsTab = uitab(app.TabGroup);
            app.RCPsTab.Title = 'RCP''s';
            app.UIAxes = uiaxes(app.RCPsTab);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'tau_s')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ClimatePathwayPanel = uipanel(app.RCPsTab);
            app.ClimatePathwayPanel.Title = 'Climate Pathway';
            app.RCP24CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP24CheckBox.Text = 'RCP 2.4';
            app.RCP60CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP60CheckBox.Text = 'RCP 6.0';
            app.RCP45CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP45CheckBox.Text = 'RCP 4.5';
            app.RCP85CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP85CheckBox.Text = 'RCP 8.5';
            app.clearplotButton = uibutton(app.RCPsTab, 'push');
            app.clearplotButton.ButtonPushedFcn = createCallbackFcn(app, @clearplotButtonPushed, true);
            app.clearplotButton.Text = {'clear plot'; ''};
            app.GenerateButton = uibutton(app.RCPsTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Switch = uiswitch(app.RCPsTab, 'slider');
            app.Switch.Items = {'No Permafrost', 'Permafrost'};
            app.Switch.Value = 'No Permafrost';
            app.Label = uilabel(app.RCPsTab);
            app.Label.Text = '';
            app.StartYearSpinnerLabel = uilabel(app.RCPsTab);
            app.StartYearSpinnerLabel.Text = 'Start Year';
            app.StartYearSpinner = uispinner(app.RCPsTab);
            app.StartYearSpinner.Limits = [1765 2500];
            app.StartYearSpinner.Value = 2000;
            app.EndYearSpinnerLabel = uilabel(app.RCPsTab);
            app.EndYearSpinnerLabel.Text = 'End Year';
            app.EndYearSpinner = uispinner(app.RCPsTab);
            app.EndYearSpinner.Limits = [1765 2500];
            app.EndYearSpinner.Value = 2300;
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.ForcingsPanel = uipanel(app.ToolsTab);
            app.ForcingsPanel.Title = 'Forcings';
            app.FAWm2EditFieldLabel = uilabel(app.ForcingsPanel);
            app.FAWm2EditFieldLabel.Text = 'FA [Wm^-2]';
            app.FAWm2EditField = uieditfield(app.ForcingsPanel, 'numeric');
            app.FAWm2EditField.Value = 104;
            app.FOWm2EditFieldLabel = uilabel(app.ForcingsPanel);
            app.FOWm2EditFieldLabel.Text = 'FO [Wm^-2]';
            app.FOWm2EditField = uieditfield(app.ForcingsPanel, 'numeric');
            app.FOWm2EditField.Value = 9.75;
            app.QWm2Label = uilabel(app.ForcingsPanel);
            app.QWm2Label.Text = 'Q [Wm^-2]';
            app.QWm2EditField = uieditfield(app.ForcingsPanel, 'numeric');
            app.QWm2EditField.Value = 201.73;
            app.deltaLabel = uilabel(app.ForcingsPanel);
            app.deltaLabel.Text = 'delta [ ]';
            app.deltaEditField = uieditfield(app.ForcingsPanel, 'numeric');
            app.deltaEditField.Value = 0.6;
            app.CalculatorPanel = uipanel(app.ToolsTab);
            app.CalculatorPanel.Title = 'Calculator';
            app.CalculateButton = uibutton(app.CalculatorPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BiasButtonGroup = uibuttongroup(app.CalculatorPanel);
            app.BiasButtonGroup.Title = 'Bias';
            app.ColdButton = uiradiobutton(app.BiasButtonGroup);
            app.ColdButton.Text = 'Cold';
            app.TemperateButton = uiradiobutton(app.BiasButtonGroup);
            app.TemperateButton.Text = 'Temperate';
            app.TemperateButton.Value = true;
            app.WarmButton = uiradiobutton(app.BiasButtonGroup);
            app.WarmButton.Text = 'Warm';
            app.GHGsPanel = uipanel(app.CalculatorPanel);
            app.GHGsPanel.Title = 'GHG''s';
            app.muppmEditFieldLabel = uilabel(app.GHGsPanel);
            app.muppmEditFieldLabel.Text = 'mu [ppm]';
            app.muppmEditField = uieditfield(app.GHGsPanel, 'numeric');
            app.muppmEditField.Value = 407.4;
            app.nuppbEditFieldLabel = uilabel(app.GHGsPanel);
            app.nuppbEditFieldLabel.Text = 'nu [ppb]';
            app.nuppbEditField = uieditfield(app.GHGsPanel, 'numeric');
            app.nuppbEditField.Value = 1875;
            app.tau_sEditFieldLabel = uilabel(app.CalculatorPanel);
            app.tau_sEditFieldLabel.Text = 'tau_s';
            app.tau_sEditField = uieditfield(app.CalculatorPanel, 'numeric');
            app.HRpermafrostrelatedPanel = uipanel(app.ToolsTab);
            app.HRpermafrostrelatedPanel.Title = 'HR (permafrost-related)';
            app.Q10EditFieldLabel = uilabel(app.HRpermafrostrelatedPanel);
            app.Q10EditFieldLabel.Text = 'Q10';
            app.Q10EditField = uieditfield(app.HRpermafrostrelatedPanel, 'numeric');
            app.Q10EditField.Limits = [0.01 Inf];
            app.Q10EditField.Value = 1.5;
            app.LabelonplotCheckBox = uicheckbox(app.HRpermafrostrelatedPanel);
            app.LabelonplotCheckBox.Text = 'Label on plot';
            % Show the figure after all components are created
            app.USRA_EBM.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.USRA_EBM)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USRA_EBM)
        end
    end
end